classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        RunCSPButton  matlab.ui.control.Button
        Slider        matlab.ui.control.Slider
        SliderLabel   matlab.ui.control.Label
        UIAxes2       matlab.ui.control.UIAxes
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        EEG % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            recName = 'eegmmidb/S001/S001R03.edf';
            [ eeg, fs, ts] = rdsamp(recName, 1:64);
            eeg = transpose(eeg);
            app.EEG = eeg;
            
            plot(app.UIAxes, eeg(1, :))
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = int32(event.Value);
            plot(app.UIAxes, app.EEG(changingValue, :))
            
            app.UIAxes.Title.String = strcat("EEG Signal Preview Channel ", int2str(changingValue));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'EEG SIgnal Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'CSP')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 64];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 1;
            app.RunCSPButton = uibutton(app.UIFigure, 'push');
            app.RunCSPButton.Text = 'Run CSP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end